classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CompositeApproachPanel         matlab.ui.container.Panel
        EmergencyStopButton            matlab.ui.control.StateButton
        ChannelEditField               matlab.ui.control.NumericEditField
        ChannelEditFieldLabel          matlab.ui.control.Label
        Set_ChannelButton              matlab.ui.control.Button
        ReverseButton                  matlab.ui.control.StateButton
        AutoApproachButton             matlab.ui.control.Button
        ForwardButton                  matlab.ui.control.StateButton
        StepsEditField                 matlab.ui.control.NumericEditField
        StepsEditFieldLabel            matlab.ui.control.Label
        SetStepsButton                 matlab.ui.control.Button
        RunonHardwarePanel             matlab.ui.container.Panel
        DeployToF28379DSwitch          matlab.ui.control.Switch
        DeployToF28379DSwitchLabel     matlab.ui.control.Label
        SampleBiasPanel                matlab.ui.container.Panel
        Set_BiasButton                 matlab.ui.control.Button
        BiasVEditField                 matlab.ui.control.NumericEditField
        BiasVEditFieldLabel            matlab.ui.control.Label
        ImagePanel                     matlab.ui.container.Panel
        RealtimeImageButton            matlab.ui.control.Button
        ImageAndExportTXTButton        matlab.ui.control.Button
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        ScanPanel                      matlab.ui.container.Panel
        PixelsPerLinepixelEditField    matlab.ui.control.NumericEditField
        PixelsPerLinepixelEditFieldLabel  matlab.ui.control.Label
        ScanLineslineEditField         matlab.ui.control.NumericEditField
        ScanLineslineEditFieldLabel    matlab.ui.control.Label
        FastAxisPeriodsEditField       matlab.ui.control.NumericEditField
        FastAxisPeriodsEditFieldLabel  matlab.ui.control.Label
        ScanRangemEditField            matlab.ui.control.NumericEditField
        ScanRangemEditFieldLabel       matlab.ui.control.Label
        StartScanSwitch                matlab.ui.control.Switch
        StartScanSwitchLabel           matlab.ui.control.Label
        Set_ScanButton                 matlab.ui.control.Button
        PIControllerPanel              matlab.ui.container.Panel
        Set_approachPIButton           matlab.ui.control.Button
        approachPISwitch               matlab.ui.control.Switch
        approachPIDLabel               matlab.ui.control.Label
        TimeScope3                     matlab.ui.scope.TimeScope
        scanPIDSwitch                  matlab.ui.control.Switch
        scanPIDSwitchLabel             matlab.ui.control.Label
        I_gainEditField                matlab.ui.control.NumericEditField
        I_gainEditFieldLabel           matlab.ui.control.Label
        SetpointnAEditField            matlab.ui.control.NumericEditField
        SetpointnAEditFieldLabel       matlab.ui.control.Label
        P_gainEditField                matlab.ui.control.NumericEditField
        P_gainEditFieldLabel           matlab.ui.control.Label
        Set_PIButton                   matlab.ui.control.Button
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Set_BiasButton
        function Set_BiasButtonPushed(app, event)
        % ========== 添加的代码开始 ==========
modelName = 'PID666';  % Simulink模型名称

% % 检查模型是否已加载，未加载则加载
% if ~bdIsLoaded(modelName)
%     load_system(modelName);
% end

% 获取P值
P_value = app.BiasVEditField.Value;

% % 直接修改P块的Gain参数（假设块路径为'PID666/P'）
% try

set_param([modelName '/P'], 'Gain', num2str(P_value));

%     disp(['P参数已修改为: ', num2str(P_value)]);%这显示在matlab命令行窗口
% catch
%     % 如果失败，提示错误
%     errordlg('修改P参数失败，请检查模型和块名');
% end
% ========== 添加的代码结束 ==========   
        
            
        end

        % Value changed function: DeployToF28379DSwitch
        function DeployToF28379DSwitchValueChanged(app, event)
  % value = app.DeployToF28379DSwitch.Value;
        % if strcmp(value, 'on')
        %     set_param('matceshi', 'SimulationCommand', 'Start');
        %     disp('仿真开始');
        % elseif strcmp(value, 'off')
        %     set_param('matceshi', 'SimulationCommand', 'Stop');
        %     app.Lamp.Color = [0.5, 0.5, 0.5];
        %     disp('仿真停止');
%         % end
% % 获取P值
% P_value = app.BiasVEditField.Value;
% 
% % % 直接修改P块的Gain参数（假设块路径为'PID666/P'）
% % try
% 
% set_param('matceshi/P', 'Gain', num2str(P_value));
% 

            Object_model = 'PID666';
            if (app.DeployToF28379DSwitch.Value == "on" )
                try
                    % %模型初始化
                    % set_param(Object_model,'SimulationMode','external')%设置仿真模式为外部模式
                    % set_param(Object_model','ExtMode','on')%启用外部通信
                    % set_param(Object_model,'ExtModeTransport',4)%选择传输方式  0代表TCP/IP 1代表共享内存 2代表串口 3代表自定义 4代表XCP ON CAN 5代表XCP ON UDP
                    % set_param(Object_model,'SolverType','Fixed-step')%设置求解器
                    % set_param(Object_model,'Fixedstep','3e-3')%设置步长
                    % set_param(Object_model,'StopTime','inf')%持续时间
                    %打开模型
                    % open_system(Object_model)
                    set_param(Object_model,'SimulationCommand','Start')%启动仿真
                    % app.Lamp.Color = [0,1,0];
                    disp('仿真开始')
%                     DataRead_and_Plot()
                catch
                    errordlg('模型错误或模型不存在')
                    % app.Lamp.Color = [0.5,0.5,0.5];
                    app.DeployToF28379DSwitch.Value = "off";
                end
            elseif (app.DeployToF28379DSwitch.Value == "off")
                set_param(Object_model,'SimulationCommand','Stop')%停止仿真
                % save_system(Object_model)
                % disp('仿真已停止并保存')
                % pause(2)
                % % close_system(Object_model)
                % % app.Lamp.Color = [0.5,0.5,0.5];
                disp('模型已关闭')
            end
        end

        % Value changed function: StartScanSwitch
        function StartScanSwitchValueChanged(app, event)
            value = app.StartScanSwitch.Value;
            modelName = 'PID666';
            if (app.StartScanSwitch.Value == "on" )
               set_param([modelName '/CCC'], 'Value', num2str(1));

            elseif (app.StartScanSwitch.Value == "off")
               set_param([modelName '/CCC'], 'Value', num2str(0));
                
            end
        end

        % Button pushed function: Set_PIButton
        function Set_PIButtonPushed(app, event)
          modelName = 'PID666';  % Simulink模型名称

         
% 获取P值
E_value = app.P_gainEditField.Value;

set_param([modelName '/EE'], 'Gain', num2str(E_value));   


% 获取P值
F_value = app.I_gainEditField.Value;

set_param([modelName '/FF'], 'Gain', num2str(F_value));  


% 获取P值
G_value = app.SetpointnAEditField.Value;

%SetpointnAEditField输入的是1nA,PI设定点只关心电压
%constant模块输入的是nA，1*(1e-9)A*3e7=0.03V,这个电压还要经过线性化,ln(0.03)才是PI的constant设定点输入的值
setpoint_value = log( G_value*(1e-9)*(3e7) );

set_param([modelName '/GG'], 'Gain', num2str(setpoint_value));   


        end

        % Button pushed function: Set_ScanButton
        function Set_ScanButtonPushed(app, event)
        modelName = 'PID666';  % Simulink模型名称

% 获取P值
P_value = app.ScanRangemEditField.Value;

%输入的扫描范围是μm为单位，而simulink需要的是SPI扩展DAC输出的电压幅值
%SPI扩展的DAC连接的XY扫描台自带12倍放大功能，80微米对应120V，所以gain为0.67μm/V
%0.8V幅值A*2倍(得P-P)*12倍(XY放大器)*0.67μm/V = 12.8μm
%0.6V幅值A*2倍(得P-P)*15倍(XY放大器)*3.33μm/V = 59.9μm

P_value = P_value / 3.33/15;

%原始代码三角波生成输入的是A幅值，而扫描范围是P-P峰峰值。P-P = 2*A
P_value = P_value/2;
P_value = round(P_value, 2);  % 四舍五入到小数点后2位
set_param([modelName '/HH'], 'Gain', num2str(P_value));   

% 获取P值
P_value = app.FastAxisPeriodsEditField.Value;

set_param([modelName '/JJ'], 'Gain', num2str(P_value));    

% 获取P值
hangshu_256 = app.ScanLineslineEditField.Value;
T_fast = app.FastAxisPeriodsEditField.Value;
T_chixu = T_fast*hangshu_256;
T_slow = 2*T_chixu;
set_param([modelName '/NN'], 'Gain', num2str(T_chixu));  
set_param([modelName '/MM'], 'Gain', num2str(T_slow));  

        end

        % Value changed function: scanPIDSwitch
        function scanPIDSwitchValueChanged(app, event)
            value = app.scanPIDSwitch.Value;
            modelName = 'PID666';
            if (app.scanPIDSwitch.Value == "on" )
               set_param([modelName '/KKK'], 'Gain', num2str(1));

            elseif (app.scanPIDSwitch.Value == "off")
               set_param([modelName '/KKK'], 'Gain', num2str(0));
                
            end    
        end

        % Button pushed function: ImageAndExportTXTButton
        function ImageAndExportTXTButtonPushed(app, event)
        
lie = app.PixelsPerLinepixelEditField.Value;%图像每行有多少个像素点，用于模仿gwyddion的加权平均算法一行只保留256个点

% T_fast = 3; 
%三角波快轴扫描周期，单位为s秒

T_fast = app.FastAxisPeriodsEditField.Value;


f_s = 10000;


% 手动输入需要提取的行数（上升段个数）
%也就是周期数
L_hang  = app.ScanLineslineEditField.Value;

mm=1;%数据成像间隔

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

%在线编译结束后，信号记录的数据在工作区，这是提取工作区数据
logsout = evalin('base', 'logsout');
% hh = evalin('base', 'hh');

data_start=1;

% % hh_tunnelcurrent=hh.data{6}.Values.Data(data_start:mm:end);%隧道电流

hh_time=logsout{2}.Values.Time(data_start:mm:end);%时间
hh_x=logsout{2}.Values.Data(data_start:mm:end);%x轴扫描信号（快轴）
hh_y=logsout{4}.Values.Data(data_start:mm:end);%y轴扫描信号（慢轴）
hh_pi=logsout{6}.Values.Data(data_start:mm:end);%z轴pi形貌信号

% hh_tunnelcurrent=hh.data{6}.Values.Data(data_start:mm:end);%隧道电流
% 
% hh_time=hh.data{1}.Values.Time(data_start:mm:end);%时间
% hh_x=hh.data{1}.Values.Data(data_start:mm:end);%x轴扫描信号（快轴）
% hh_y=hh.data{2}.Values.Data(data_start:mm:end);%y轴扫描信号（慢轴）
% hh_pi=hh.data{4}.Values.Data(data_start:mm:end);%z轴pi形貌信号

% figure(1)
% % 出图
% plot(hh_time,hh_x, 'b', 'LineWidth', 2);hold on;grid on;
% plot(hh_time,hh_y, 'k', 'LineWidth', 2);
% dcm = datacursormode(gcf);          % 获取 Data Cursor 模式对象
% set(dcm, 'UpdateFcn', @myDataTip);  % 设置自定义回调函数
%% 三角波单向峰谷索引
peaks_x = find(diff(sign(diff(hh_x))) < 0) + 1;   % 找峰值点索引
valleys_x = find(diff(sign(diff(hh_x))) > 0) + 1; % 找谷底点索引


%以上数据只关心自动得到三角波第一个起始点，后续

% 只使用第一个谷值索引作为起始参考点
first_idx = valleys_x(1);

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% %如果想自定义从第几个三角波周期开始成像，可以从plot原始点的图12查起始是第几个点，手动输入
% % first_peak_idx = ;
% figure(12)
% 
% plot(hh_x, 'b', 'LineWidth', 2);

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

points_per_rise = (T_fast/2)*f_s;%一个上升段会有多少个点

% 或者可以直接指定：L_hang = 40;

% 初始化存储数组
C_x = cell(1, L_hang);  % 提取x轴上升段
C_y = cell(1, L_hang);  % 提取y轴对应x轴上升段
C_z = cell(1, L_hang);  % 提取z轴对应x轴上升段

% 提取每个上升段的数据
for i = 1:L_hang
    % 计算当前上升段的起始索引
    start_idx = first_idx + (i-1) * T_fast * f_s;


    % 提取当前上升段的数据
    idx_range = start_idx:(start_idx + points_per_rise - 1);
    
    C_x{i} = struct( ...
        'time', hh_time(idx_range), ...
        'scanx', hh_x(idx_range) ...
        );
    
    C_y{i} = struct( ...
        'time', hh_time(idx_range), ...
        'scanx', hh_y(idx_range) ...
        );
    
    C_z{i} = struct( ...
        'time', hh_time(idx_range), ...
        'scanx', hh_pi(idx_range) ...
        );
end


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


%% 三维成像


%% 三维成像
%% 三维成像 - 加权平均方法
%% 三维成像 - 加权平均方法（修正版）
num_lines = length(C_z);  % 扫描线数量
points_per_line = length(C_z{1}.scanx);  % 每条线的原始点数

% 创建结果矩阵
Z_matrix = zeros(num_lines, lie);
X_matrix = zeros(num_lines, lie);
Y_matrix = zeros(num_lines, lie);

% 计算每个像素对应的原始数据范围
% 将原始点数等分为lie个区间
bin_edges = round(linspace(1, points_per_line+1, lie+1));

for i = 1:num_lines
    % 获取当前扫描线的原始数据
    z_line = C_z{i}.scanx;  % 形貌信号% 去除数据包中的时间信息
    x_line = C_x{i}.scanx;  % x坐标
    y_line = C_y{i}.scanx;  % y坐标
    
    for j = 1:lie
        % 确定当前像素对应的原始数据索引范围
        start_idx = bin_edges(j);
        end_idx = bin_edges(j+1) - 1;
        
        % 确保索引有效
        start_idx = max(1, min(start_idx, points_per_line));
        end_idx = max(1, min(end_idx, points_per_line));
        
        if start_idx <= end_idx
            % 获取该范围内的所有数据点
            indices = start_idx:end_idx;
            
            if length(indices) > 0
                % 提取该范围内的数据
                z_values = z_line(indices);
                x_values = x_line(indices);
                y_values = y_line(indices);
                
                if length(indices) == 1
                    % 如果只有一个点，直接使用该点
                    Z_matrix(i, j) = z_values;
                    X_matrix(i, j) = x_values;
                    Y_matrix(i, j) = y_values;
                else
                    % 计算加权平均值
                    % 使用高斯权重：中间点权重高，边缘点权重低
                    center = mean(indices);
                    width = (end_idx - start_idx + 1) / 2;
                    
                    % 计算高斯权重
                    if width > 0
                        weights = exp(-((indices - center) / width).^2);
                        weights = weights / sum(weights);  % 归一化
                        
                        % 计算加权平均
                        Z_matrix(i, j) = sum(z_values .* weights(:));
                        X_matrix(i, j) = sum(x_values .* weights(:));
                        Y_matrix(i, j) = sum(y_values .* weights(:));
                    else
                        % 如果宽度为0，使用简单平均
                        Z_matrix(i, j) = mean(z_values);
                        X_matrix(i, j) = mean(x_values);
                        Y_matrix(i, j) = mean(y_values);
                    end
                end
            else
                % 如果该像素没有数据点，设置为NaN
                Z_matrix(i, j) = NaN;
                X_matrix(i, j) = NaN;
                Y_matrix(i, j) = NaN;
            end
        else
            % 如果索引范围无效，设置为NaN
            Z_matrix(i, j) = NaN;
            X_matrix(i, j) = NaN;
            Y_matrix(i, j) = NaN;
        end
    end
end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

%% 倾斜矫正
[M, N] = size(Z_matrix);
% 求解最小二乘平面 Z = aX + bY + c；
A = [X_matrix(:), Y_matrix(:), ones(M*N, 1)];%构建线性最小二乘 (LS, Least Squares) 拟合方程的矩阵
coeff = A \ Z_matrix(:);      %得到LS方程系数 [a; b; c]
% 得到拟合平面
plane = reshape(A*coeff, size(Z_matrix));
% 校正
Z_corrected = Z_matrix - plane;


% figure(18);
% surf(X_matrix*8, Y_matrix*8, (Z_corrected-min(Z_corrected(:)))*1.5*30*22/1e3, 'EdgeColor', 'none');
% %1.5为同相比例放大器倍数，
% % 30为电压放大器倍数，22为压电堆叠的位移/电压关系单位nm，
% % 1e3将nm转化为um
% % xlim([0 64]);
% xlabel('x/um');ylabel('y/um');zlabel('z/um');
% view([0 0]);    % 从 y 轴正方向看
% % xlim([0 64]);
% set(gcf,'Position',[50 50 400 300]);
% set(gca,'FontName','Times New Roman','FontSize',11);
% % %print(gcf,'-dtiff','-r600','C:\Users\liush\Desktop\损v0');
% hold on;
% % % figure%绘制最小二乘平面
% % surf(X_matrix*8, Y_matrix*8, plane*1.5*30*22/1e3, 'EdgeColor', 'none');

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

% 三维成像-平面模式
% figure(16);
surf(app.UIAxes, X_matrix*15*3.33, Y_matrix*15*3.33, -(Z_corrected-min(Z_corrected(:)))*15*3.33, 'EdgeColor', 'none');
%80微米对应120V，所以扫描器0.66V/μm，扫描器自带12倍放大，所以0.66V/μm * 12倍 = 8V/μm
% %1.5为同相比例放大器倍数，
% % 30为电压放大器倍数，22为压电堆叠的位移/电压关系单位nm，
% % 1e3将nm转化为um

%0.6V幅值A*2倍(得P-P)*15倍(XY放大器)*3.33μm/V = 59.9μm

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

% 修改配色方案：深褐色 -> ... -> 浅黄色
% % 可以根据需要调整中间颜色
% terrain_cmap = [0.4 0.2 0.0;    % 深褐色（最低点）
%                 0.6 0.4 0.2;    % 中褐色
%                 0.8 0.6 0.3;    % 浅褐色
%                 0.9 0.8 0.5;    % 土黄色
%                 1.0 0.9 0.6;    % 浅黄色
%                 1.0 1.0 0.8];   % 非常浅的黄色/接近白色（最高点）

% 或者更简单的渐变：深褐色直接到浅黄色
terrain_cmap = [70,22,0;   % 深褐色
               224,198,72]/256;  % 浅黄色

% % 或者更简单的渐变：深褐色直接到浅黄色
% terrain_cmap = [0.4, 0.2, 0.0;   % 深褐色
%                1, 0.843, 0];  % 浅黄色


% 创建渐变
n_colors = 256;
custom_cmap = interp1(linspace(0, 1, size(terrain_cmap, 1)), ...
                      terrain_cmap, linspace(0, 1, n_colors));

colormap(app.UIAxes, custom_cmap);

app.UIAxes.XLabel.String = 'x/um';
app.UIAxes.YLabel.String = 'y/um';
app.UIAxes.ZLabel.String = 'z/um';
% 8. 设置字体（App Designer方式）
    app.UIAxes.FontName = 'Times New Roman';
    app.UIAxes.FontSize = 11;
% set(gcf,'Position',[50 50 400 300]);
% set(gca,'FontName','Times New Roman','FontSize',11);

% 三维成像-三维模式

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% %App中放上俯视图
%  % 2. 绘制三维曲面图（去掉网格线）
%     surf(app.UIAxes2, ...
%         X_matrix * 8, ...
%         Y_matrix * 8, ...
%         -(Z_matrix - min(Z_matrix(:))) * 30 * 22 / 1e3, ...
%         'EdgeColor', 'none');
% 
%     % 3. 设置俯视图（XY平面视图）
%     view(app.UIAxes2, 2);  % 关键：指定在app.UIAxes上设置视图
% 
%     % 4. 设置颜色映射
%     colormap(app.UIAxes2, 'parula');
% 
%     % 5. 添加颜色条并设置标签
%     c = colorbar(app.UIAxes2);  % 关键：指定在app.UIAxes上添加颜色条
%     c.Label.String = 'z/um';
% 
%     % 6. 设置坐标轴标签
%     app.UIAxes2.XLabel.String = 'x/um';
%     app.UIAxes2.YLabel.String = 'y/um';
%     app.UIAxes2.ZLabel.String = 'z/um';
% 
%     % 7. 设置坐标轴范围（可选）
%     % app.UIAxes.XLim = [0, 60];
%     % app.UIAxes.YLim = [0, 60];
% 
%     % 8. 设置字体（App Designer方式）
%     app.UIAxes2.FontName = 'Times New Roman';
%     app.UIAxes2.FontSize = 11;


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


% figure(17);
% surf(X_matrix*8, Y_matrix*8, Z_matrix*1.5*30*22/1e3, 'EdgeColor', 'none');
% % view(2);  % 俯视图
% % colormap('parula');
% % c=colorbar;
% % ylabel(c, 'z/um');
% % xlim([0 64]);
% xlabel('x/um');ylabel('y/um');zlabel('z/um');
% % view([0 0]);    % 从 y 轴正方向看
% set(gcf,'Position',[450 450 400 300]);
% set(gca,'FontName','Times New Roman','FontSize',11);
% % %print(gcf,'-dtiff','-r600','C:\Users\liush\Desktop\损v0');


% %% 切片
% figure(19)
% y_index=2;%行索引(y轴切片 )
% plot(X_matrix(y_index,:),Z_matrix(y_index,:));%原始数据
% hold on;grid on;
% plot(X_matrix(y_index,:),Z_corrected(y_index,:)-min(Z_corrected(:)));%倾斜校正后数据
% legend('Original Data','Tilt-Corrected Data');
% xlabel('x/um');ylabel('z/um');
% set(gcf,'Position',[50 50 400 300]);
% set(gca,'FontName','Times New Roman','FontSize',11);


%% 数据导出至Gwyddion
% data = [X_matrix(:)*8, Y_matrix(:)*8, (Z_corrected(:)-min(Z_corrected(:)))*1.5*30*22/1e3];
data = [X_matrix(:)*15*3.33, Y_matrix(:)*15*3.33, -( Z_matrix(:)*15*3.33) ];%将X变成一列
subdir = 'data';   % 工作目录下的 data 文件夹
% filename2 = "scan_data_" + filename + ".txt";
filename2 = 'gwyddion_data.txt';
writematrix(data, filename2, 'Delimiter', 'tab');
% writematrix(Z_matrix, 'scan_z_data.txt', 'Delimiter', 'tab');

% figure(20)
% X_matrix_k = X_matrix(:);
% Y_matrix_k=Y_matrix(:);
% Z_matrix_k=Z_matrix(:)*30*22/1e3;
% plot3(X_matrix_k,Y_matrix_k,Z_matrix_k,'o','MarkerSize',1.5);%普通plot是点和点之间的连线，必须换成独立的点不连线。
% % plot3(X_matrix_k,Y_matrix_k,Z_matrix_k);%普通plot是点和点之间的连线，必须换成独立的点不连线。
% xlabel('x fast');
% ylabel('y slow');
% T_s=1/10000;
% 
% 
% 
% figure(21)
% y_index=2;%行索引(y轴切片 )
% plot(X_matrix(y_index,:),Z_matrix(y_index,:));%原始数据
% hold on;grid on;
% plot(X_matrix(y_index,:),Z_corrected(y_index,:)-min(Z_corrected(:)));%倾斜校正后数据
% legend('Original Data','Tilt-Corrected Data');
% xlabel('x/um');ylabel('z/um');
% set(gcf,'Position',[50 50 400 300]);
% set(gca,'FontName','Times New Roman','FontSize',11);

        end

        % Button pushed function: RealtimeImageButton
        function RealtimeImageButtonPushed(app, event)


   t = timer('StartDelay', 20, 'Period', 60, ...
              'TasksToExecute', 30, 'ExecutionMode', 'fixedRate');
    
   % 将app作为参数传递给匿名函数
   t.TimerFcn = @(~,~) jiaoben_timerRECALL(app);
    
   start(t);
    
   % % 保存定时器到app属性以便后续管理
   % app.UpdateTimer = t;
  

        end

        % Value changed function: approachPISwitch
        function approachPISwitchValueChanged(app, event)
            value = app.approachPISwitch.Value;
             
            modelName = 'PID666';
            if (app.approachPISwitch.Value == "on" )
               set_param([modelName '/K666'], 'Gain', num2str(1));

            elseif (app.approachPISwitch.Value == "off")
               set_param([modelName '/K666'], 'Gain', num2str(0));
                
            end    
            
        end

        % Button pushed function: Set_approachPIButton
        function Set_approachPIButtonPushed(app, event)
            modelName_CPU2 = 'cpu2';  
% 获取P值
E_value = app.P_gainEditField.Value;


set_param([modelName_CPU2 '/PPPP'], 'Gain', num2str(E_value));   


% 获取P值
F_value = app.I_gainEditField.Value;


set_param([modelName_CPU2 '/TTTT'], 'Gain', num2str(F_value));  


% 获取P值
G_value = app.SetpointnAEditField.Value;

%SetpointnAEditField输入的是1nA,PI设定点只关心电压
%constant模块输入的是nA，1*(1e-9)A*3e7=0.03V,这个电压还要经过线性化,ln(0.03)才是PI的constant设定点输入的值
setpoint_value = log( G_value*(1e-9)*(3e7) );


set_param([modelName_CPU2 '/QQQQ'], 'Gain', num2str(setpoint_value));   
        end

        % Value changed function: ForwardButton
        function ForwardButtonValueChanged(app, event)

        set_param('AMI_2_2_2/zhengfan666', 'Gain', '1');
        disp('正转');


    if app.ForwardButton.Value
        % 1. 先设为 1
        set_param('AMI_2_2_2/begin666', 'Gain', '1');
        disp('Gain = 1');
        
        % 2. 短暂延迟（如果需要观察效果）
        pause(0.1);  % 100ms 延迟
        
        % 3. 再设为 0
        set_param('AMI_2_2_2/begin666', 'Gain', '0');
        disp('Gain = 0');
        
        % 4. 让按钮弹起
        app.ForwardButton.Value = false;
    end
    % else 分支完全不需要

        end

        % Button pushed function: AutoApproachButton
        function AutoApproachButtonPushed(app, event)
        

set_param('AMI_2_2_2/zhengfan666', 'Gain', '0');
        disp('反转');
pause(1)

for i = 1:100
    

    %任何时刻点击急停按钮 → 下一次循环立即停
    if app.EmergencyStopButton.Value
    disp('!!! EMERGENCY STOP !!!')
    pause(0.1);  % 100ms 延迟
    app.EmergencyStopButton.Value = false;

    break
    end

    modelName = 'PID666';

    set_param([modelName '/K666'], 'Gain', num2str(1)); % 开启逼近PI
    disp('approach_on！！！');
    
    pause(8);

    % 获取隧道电流值      
    fuelRun = Simulink.sdi.getCurrentSimulationRun('PID666');

    X_data = getSignalByIndex(fuelRun,3);
    
    X_data_time = export(X_data);
    
    % value_tunnel = X_data_time.data(end);
    %取最后120个点的平均值，防止恰好取到0V隧道电流
    value_tunnel = mean(X_data_time.data(end-119:end));
    pause(2);

    disp(value_tunnel)

    if value_tunnel < 0.3
    
        % 关闭逼近PI
        set_param([modelName '/K666'], 'Gain', num2str(0));
    
        pause(5);
        
        set_param('AMI_2_2_2/begin666', 'Gain', '1');
        disp('Gain = 1');
        
        pause(0.1);
        
        set_param('AMI_2_2_2/begin666', 'Gain', '0');
        disp('Gain = 0');
        
        app.ForwardButton.Value = false;
    
    else
        disp('value_tunnel >= 0.3, stop loop');
        break   % 直接结束for循环
    end
    
    drawnow
    pause(1)
    
end


        end

        % Callback function
        function valuegetButtonPushed(app, event)
fuelRun = Simulink.sdi.getCurrentSimulationRun('PID666');

X_data = getSignalByIndex(fuelRun,3);

X_data_time = export(X_data);

hh_x = X_data_time.data(end);

disp(hh_x)
    
        end

        % Callback function
        function ReverseButtonPushed(app, event)
     
        end

        % Button pushed function: SetStepsButton
        function SetStepsButtonPushed(app, event)
modelName = 'AMI_2_2_2';  % Simulink模型名称

P_value = app.StepsEditField.Value;

set_param([modelName '/bushu666'], 'Gain', num2str(P_value));

        end

        % Value changed function: ReverseButton
        function ReverseButtonValueChanged(app, event)
            value = app.ReverseButton.Value;
             set_param('AMI_2_2_2/zhengfan666', 'Gain', '0');
        disp('反转');


    if app.ReverseButton.Value
        % 1. 先设为 1
        set_param('AMI_2_2_2/begin666', 'Gain', '1');
        disp('Gain = 1');
        
        % 2. 短暂延迟（如果需要观察效果）
        pause(0.1);  % 100ms 延迟
        
        % 3. 再设为 0
        set_param('AMI_2_2_2/begin666', 'Gain', '0');
        disp('Gain = 0');
        
        % 4. 让按钮弹起
        app.ReverseButton.Value = false;
    end
    % else 分支完全不需要 
        end

        % Callback function
        function ChannelButtonPushed(app, event)
           
        end

        % Button pushed function: Set_ChannelButton
        function Set_ChannelButtonPushed(app, event)
            modelName = 'AMI_2_2_2';  % Simulink模型名称

P_value = app.ChannelEditField.Value;

set_param([modelName '/tongdao666'], 'Gain', num2str(P_value));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PIControllerPanel = uipanel(app.UIFigure);
            app.PIControllerPanel.Title = ' PI Controller';
            app.Set_PIButton = uibutton(app.PIControllerPanel, 'push');
            app.Set_PIButton.ButtonPushedFcn = createCallbackFcn(app, @Set_PIButtonPushed, true);
            app.Set_PIButton.Text = 'Set_PI';
            app.P_gainEditFieldLabel = uilabel(app.PIControllerPanel);
            app.P_gainEditFieldLabel.Text = 'P_gain';
            app.P_gainEditField = uieditfield(app.PIControllerPanel, 'numeric');
            app.SetpointnAEditFieldLabel = uilabel(app.PIControllerPanel);
            app.SetpointnAEditFieldLabel.Text = 'Setpoint(nA)';
            app.SetpointnAEditField = uieditfield(app.PIControllerPanel, 'numeric');
            app.I_gainEditFieldLabel = uilabel(app.PIControllerPanel);
            app.I_gainEditFieldLabel.Text = 'I_gain';
            app.I_gainEditField = uieditfield(app.PIControllerPanel, 'numeric');
            app.scanPIDSwitchLabel = uilabel(app.PIControllerPanel);
            app.scanPIDSwitchLabel.Text = 'scanPID';
            app.scanPIDSwitch = uiswitch(app.PIControllerPanel, 'slider');
            app.scanPIDSwitch.Items = {'off', 'on'};
            app.scanPIDSwitch.ValueChangedFcn = createCallbackFcn(app, @scanPIDSwitchValueChanged, true);
            app.scanPIDSwitch.Value = 'off';
            app.TimeScope3 = uitimescope(app.PIControllerPanel);
            app.TimeScope3.LegendNames = {'Sinsin:1'};
            app.TimeScope3.Title = 'PI output';
            app.TimeScope3.YLabel = 'PI output (V)';
            app.approachPIDLabel = uilabel(app.PIControllerPanel);
            app.approachPIDLabel.Text = 'approachPI';
            app.approachPISwitch = uiswitch(app.PIControllerPanel, 'slider');
            app.approachPISwitch.Items = {'off', 'on'};
            app.approachPISwitch.ValueChangedFcn = createCallbackFcn(app, @approachPISwitchValueChanged, true);
            app.approachPISwitch.Value = 'off';
            app.Set_approachPIButton = uibutton(app.PIControllerPanel, 'push');
            app.Set_approachPIButton.ButtonPushedFcn = createCallbackFcn(app, @Set_approachPIButtonPushed, true);
            app.Set_approachPIButton.Text = 'Set_approachPI';
            app.ScanPanel = uipanel(app.UIFigure);
            app.ScanPanel.Title = 'Scan';
            app.Set_ScanButton = uibutton(app.ScanPanel, 'push');
            app.Set_ScanButton.ButtonPushedFcn = createCallbackFcn(app, @Set_ScanButtonPushed, true);
            app.Set_ScanButton.Text = 'Set_Scan';
            app.StartScanSwitchLabel = uilabel(app.ScanPanel);
            app.StartScanSwitchLabel.Text = 'StartScan';
            app.StartScanSwitch = uiswitch(app.ScanPanel, 'slider');
            app.StartScanSwitch.Items = {'off', 'on'};
            app.StartScanSwitch.ValueChangedFcn = createCallbackFcn(app, @StartScanSwitchValueChanged, true);
            app.StartScanSwitch.Value = 'off';
            app.ScanRangemEditFieldLabel = uilabel(app.ScanPanel);
            app.ScanRangemEditFieldLabel.Text = 'ScanRange(μm)';
            app.ScanRangemEditField = uieditfield(app.ScanPanel, 'numeric');
            app.FastAxisPeriodsEditFieldLabel = uilabel(app.ScanPanel);
            app.FastAxisPeriodsEditFieldLabel.Text = 'FastAxisPeriod(s)';
            app.FastAxisPeriodsEditField = uieditfield(app.ScanPanel, 'numeric');
            app.ScanLineslineEditFieldLabel = uilabel(app.ScanPanel);
            app.ScanLineslineEditFieldLabel.Text = 'ScanLines(line)';
            app.ScanLineslineEditField = uieditfield(app.ScanPanel, 'numeric');
            app.PixelsPerLinepixelEditFieldLabel = uilabel(app.ScanPanel);
            app.PixelsPerLinepixelEditFieldLabel.Text = 'PixelsPerLine(pixel)';
            app.PixelsPerLinepixelEditField = uieditfield(app.ScanPanel, 'numeric');
            app.ImagePanel = uipanel(app.UIFigure);
            app.ImagePanel.Title = 'Image';
            app.UIAxes = uiaxes(app.ImagePanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes3 = uiaxes(app.ImagePanel);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.ImageAndExportTXTButton = uibutton(app.ImagePanel, 'push');
            app.ImageAndExportTXTButton.ButtonPushedFcn = createCallbackFcn(app, @ImageAndExportTXTButtonPushed, true);
            app.ImageAndExportTXTButton.Text = 'ImageAndExportTXT';
            app.RealtimeImageButton = uibutton(app.ImagePanel, 'push');
            app.RealtimeImageButton.ButtonPushedFcn = createCallbackFcn(app, @RealtimeImageButtonPushed, true);
            app.RealtimeImageButton.Text = 'RealtimeImage';
            app.SampleBiasPanel = uipanel(app.UIFigure);
            app.SampleBiasPanel.Title = 'Sample Bias';
            app.BiasVEditFieldLabel = uilabel(app.SampleBiasPanel);
            app.BiasVEditFieldLabel.Text = 'Bias(V)';
            app.BiasVEditField = uieditfield(app.SampleBiasPanel, 'numeric');
            app.Set_BiasButton = uibutton(app.SampleBiasPanel, 'push');
            app.Set_BiasButton.ButtonPushedFcn = createCallbackFcn(app, @Set_BiasButtonPushed, true);
            app.Set_BiasButton.Text = 'Set_Bias';
            app.RunonHardwarePanel = uipanel(app.UIFigure);
            app.RunonHardwarePanel.Title = 'Run on Hardware';
            app.DeployToF28379DSwitchLabel = uilabel(app.RunonHardwarePanel);
            app.DeployToF28379DSwitchLabel.Text = 'DeployToF28379D';
            app.DeployToF28379DSwitch = uiswitch(app.RunonHardwarePanel, 'slider');
            app.DeployToF28379DSwitch.Items = {'off', 'on'};
            app.DeployToF28379DSwitch.ValueChangedFcn = createCallbackFcn(app, @DeployToF28379DSwitchValueChanged, true);
            app.DeployToF28379DSwitch.Value = 'off';
            app.CompositeApproachPanel = uipanel(app.UIFigure);
            app.CompositeApproachPanel.Title = 'Composite Approach';
            app.SetStepsButton = uibutton(app.CompositeApproachPanel, 'push');
            app.SetStepsButton.ButtonPushedFcn = createCallbackFcn(app, @SetStepsButtonPushed, true);
            app.SetStepsButton.Text = 'Set Steps';
            app.StepsEditFieldLabel = uilabel(app.CompositeApproachPanel);
            app.StepsEditFieldLabel.Text = 'Steps';
            app.StepsEditField = uieditfield(app.CompositeApproachPanel, 'numeric');
            app.ForwardButton = uibutton(app.CompositeApproachPanel, 'state');
            app.ForwardButton.ValueChangedFcn = createCallbackFcn(app, @ForwardButtonValueChanged, true);
            app.ForwardButton.Text = 'Forward';
            app.AutoApproachButton = uibutton(app.CompositeApproachPanel, 'push');
            app.AutoApproachButton.ButtonPushedFcn = createCallbackFcn(app, @AutoApproachButtonPushed, true);
            app.AutoApproachButton.Text = 'AutoApproach';
            app.ReverseButton = uibutton(app.CompositeApproachPanel, 'state');
            app.ReverseButton.ValueChangedFcn = createCallbackFcn(app, @ReverseButtonValueChanged, true);
            app.ReverseButton.Text = 'Reverse';
            app.Set_ChannelButton = uibutton(app.CompositeApproachPanel, 'push');
            app.Set_ChannelButton.ButtonPushedFcn = createCallbackFcn(app, @Set_ChannelButtonPushed, true);
            app.Set_ChannelButton.Text = 'Set_Channel';
            app.ChannelEditFieldLabel = uilabel(app.CompositeApproachPanel);
            app.ChannelEditFieldLabel.Text = 'Channel';
            app.ChannelEditField = uieditfield(app.CompositeApproachPanel, 'numeric');
            app.EmergencyStopButton = uibutton(app.CompositeApproachPanel, 'state');
            app.EmergencyStopButton.Text = 'EmergencyStop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % Bind initialization
    methods (Access = protected)

        function createBindings(app)

            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Sinsin:1'], app.TimeScope3);
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Associate the Simulink Model
            app.Simulation = simulation('PID666');

            % Create UIFigure and components
            createComponents(app)

            % Create bindings
            createBindings(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end